classdef ClassifyTexture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        loadImageButton        matlab.ui.control.Button
        LoadImageandseewhatitcontainsLabel  matlab.ui.control.Label
        DetailsEditFieldLabel  matlab.ui.control.Label
        DetailsEditField       matlab.ui.control.EditField
        Button                 matlab.ui.control.Button
        MainButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        
        function speak(app)
            NET.addAssembly('System.Speech');
                obj=System.Speech.Synthesis.SpeechSynthesizer;
                obj.Volume=100;
                pause(1);
                Speak(obj,app.DetailsEditField.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            %uigetfile; % Could be other dialogs here
            %drawnow;
           
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               im = imread(fname);
               app.image = im;
               app.UIAxes.Visible = 'on';
               app.UIAxes.Colormap = gray(256);
               axis(app.UIAxes, 'image');
               imagesc(app.UIAxes,im);
               figure(app.UIFigure)
            end
            
                app.image=im2bw( app.image);
                %% Find the class the test image belongs
            Ftest=FeatureStatistical( app.image);
            %% Compare with the feature of training image in the database
            load db.mat
            Ftrain=db(:,1:2);
                Ctrain=db(:,3);
            for i=1:size(Ftrain,1)
                 dist(i,:)=sum(abs(Ftrain(i,:)-Ftest));
            end   
                m=find(dist==min(dist),1);
                det_class=Ctrain(m);
            if det_class== 1
                app.DetailsEditField.Value = 'Brick Detected!!!';
                app.speak();
            elseif (det_class)== 12
                app.DetailsEditField.Value ='Grass Detected!!!';
                app.speak();
            elseif (det_class)== 10
                app.DetailsEditField.Value ='Grass and brick Detected!!!'; 
                app.speak();
            end
    
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
                app.speak();
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            closereq   
            run("application")
        end

        % Value changed function: DetailsEditField
        function DetailsEditFieldValueChanged(app, event)
            value = app.DetailsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.loadImageButton = uibutton(app.UIFigure, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'load Image';
            app.LoadImageandseewhatitcontainsLabel = uilabel(app.UIFigure);
            app.LoadImageandseewhatitcontainsLabel.Text = {'Load Image and see what it contains..'; ''};
            app.DetailsEditFieldLabel = uilabel(app.UIFigure);
            app.DetailsEditFieldLabel.Text = 'Details';
            app.DetailsEditField = uieditfield(app.UIFigure, 'text');
            app.DetailsEditField.ValueChangedFcn = createCallbackFcn(app, @DetailsEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-ice-cream-24.png';
            app.Button.Text = ' ';
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = {'Main'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassifyTexture

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end