classdef filterGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        MainButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        OrginalImageLabel             matlab.ui.control.Label
        BrowseImageButton             matlab.ui.control.Button
        FilterDropDownLabel           matlab.ui.control.Label
        FilterDropDown                matlab.ui.control.DropDown
        ViewHistogramDetailsButton    matlab.ui.control.Button
        CheckIntensityofcolorsButton  matlab.ui.control.Button
        pnlfiltered                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        FilteredImageLabel            matlab.ui.control.Label
        SaveImageButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        image; % Description
        saveNewImage;    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.pnlfiltered.Visible = 'off';

        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
        closereq   
        run("application")
        
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
% Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            %uigetfile; % Could be other dialogs here
            %drawnow;
           
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               im = imread(fname);
               app.image = im;
               app.UIAxes.Visible = 'on';
               app.UIAxes.Colormap = gray(256);
               axis(app.UIAxes, 'image');
               imagesc(app.UIAxes,im);
               figure(app.UIFigure)
            end
            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
             if strcmp(value,'Black and White')
                 app.pnlfiltered.Visible ='on';
                 gray= rgb2gray(app.image);
                 app.saveNewImage = gray;
                 imshow(gray, 'parent', app.UIAxes_2);
                 figure(app.UIFigure);
             end
             
             if strcmp(value,'Make Blurr')
                 app.pnlfiltered.Visible ='on';
                 h = fspecial('motion', 50, 45);
                filteredRGB = imfilter(app.image, h,'replicate');
                app.saveNewImage = filteredRGB;
                imshow(filteredRGB, 'parent', app.UIAxes_2);
               figure(app.UIFigure);
             end
             
             if strcmp(value,'Extract Edges')
                 app.pnlfiltered.Visible ='on';
                 h = fspecial('laplacian',0.1);
                filteredRGB = imfilter(app.image, h);
                app.saveNewImage = filteredRGB;
                imshow(filteredRGB, 'parent', app.UIAxes_2);
               figure(app.UIFigure);
             end
             if strcmp(value,'Ambose Effect')
                 app.pnlfiltered.Visible ='on';
                 amborse = [-1 -1 0; -1 0 1; 0 1 1];
                 myImage = imfilter(app.image,amborse);
                 app.saveNewImage = myImage;
                imshow(myImage, 'parent', app.UIAxes_2);
               figure(app.UIFigure);
             end


        end

        % Button pushed function: ViewHistogramDetailsButton
        function ViewHistogramDetailsButtonPushed(app, event)
             figure,imhist(app.image);
        end

        % Button pushed function: CheckIntensityofcolorsButton
        function CheckIntensityofcolorsButtonPushed(app, event)
            closereq   
         run("ImageHistogramsApp");
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
              [F,P] = uiputfile();
              F = [F, '.jpg']; % Append .jpg
              fullFileName = fullfile(P, F);
              imwrite(app.saveNewImage, fullFileName);
              msgbox("image saved successfully...!");
              figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.MainButton = uibutton(app.Panel, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = {'Main'; ''};
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.OrginalImageLabel = uilabel(app.Panel);
            app.OrginalImageLabel.Text = 'Orginal Image';
            app.BrowseImageButton = uibutton(app.Panel, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.FilterDropDownLabel = uilabel(app.Panel);
            app.FilterDropDownLabel.Text = {'Filter'; ''};
            app.FilterDropDown = uidropdown(app.Panel);
            app.FilterDropDown.Items = {'Select filter', 'Make Blurr', 'Black and White', 'Extract Edges', 'Ambose Effect'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Select filter';
            app.ViewHistogramDetailsButton = uibutton(app.Panel, 'push');
            app.ViewHistogramDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewHistogramDetailsButtonPushed, true);
            app.ViewHistogramDetailsButton.Text = 'View Histogram Details';
            app.CheckIntensityofcolorsButton = uibutton(app.Panel, 'push');
            app.CheckIntensityofcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckIntensityofcolorsButtonPushed, true);
            app.CheckIntensityofcolorsButton.Text = {'Check Intensity of '; 'colors'};
            app.pnlfiltered = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.pnlfiltered);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.FilteredImageLabel = uilabel(app.pnlfiltered);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.SaveImageButton = uibutton(app.pnlfiltered, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filterGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end