classdef learningPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WelcomeReadytolearnLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        AButton                         matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        CButton                         matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        EButton                         matlab.ui.control.Button
        FButton                         matlab.ui.control.Button
        GButton                         matlab.ui.control.Button
        HButton                         matlab.ui.control.Button
        IButton                         matlab.ui.control.Button
        JButton                         matlab.ui.control.Button
        KButton                         matlab.ui.control.Button
        LButton                         matlab.ui.control.Button
        MButton                         matlab.ui.control.Button
        NButton                         matlab.ui.control.Button
        UButton                         matlab.ui.control.Button
        TButton                         matlab.ui.control.Button
        SButton                         matlab.ui.control.Button
        RButton                         matlab.ui.control.Button
        QButton                         matlab.ui.control.Button
        PButton                         matlab.ui.control.Button
        OButton                         matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        WButton                         matlab.ui.control.Button
        VButton                         matlab.ui.control.Button
        ClickonthebuttonsandlearnLabel  matlab.ui.control.Label
        MainButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        ClicktheSpeakerLabel            matlab.ui.control.Label
        TakeaQuizButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        valOfLabel; % Description
    end
    
    methods (Access = private)
        
        function speech(app,text)
        NET.addAssembly('System.Speech');
                obj=System.Speech.Synthesis.SpeechSynthesizer;
                obj.Volume=100;

                Speak(obj,text);
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            closereq   
            run("application");
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
          im = imread('A.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'A for Apple';
          pause(1);
          app.speech(app.Panel_2.Title);
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.speech(app.Panel_2.Title);
            
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
             im = imread('b.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'B for Banana';
          pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
             im = imread('c.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'C for Cookie';
          pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
        im = imread('d.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'D for DOLL';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
        im = imread('E.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'E for ELEPHANT';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
        im = imread('F.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'F for FAN';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
        im = imread('G.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'G for GUN';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
        im = imread('H.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'H for HORSE';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
        im = imread('I.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'I for IGLOO';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: JButton
        function JButtonPushed(app, event)
        im = imread('J.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'J for JELLY';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
        im = imread('K.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'K for KANGAROO';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
        im = imread('L.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'L for LION';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
        im = imread('M.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'M for MANGO';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
        im = imread('N.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'N for NEST';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
        im = imread('O.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'O for ORANGE';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
        im = imread('P.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'P for PAKISTAN';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: QButton
        function QButtonPushed(app, event)
        im = imread('Q.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'Q for QURAAN';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
        im = imread('R.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'R for RAT';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
        im = imread('S.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'S for SNAKE';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
        im = imread('T.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'T for TURTLE';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
        im = imread('U.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'U for UMBRELLA';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
        im = imread('V.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'V for VIOLIN';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
        im = imread('W.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'W for WINDOW';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
        im = imread('X.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'X for XLYOPHONE';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
        im = imread('Y.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'Y for YO YO';
            pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
        im = imread('Z.jpg');
          imshow(im, 'parent', app.UIAxes);
          app.Panel_2.Title = 'Z for ZEBRA';            
          pause(1);
          app.speech(app.Panel_2.Title);
        end

        % Button pushed function: TakeaQuizButton
        function TakeaQuizButtonPushed(app, event)
            closereq;
            run("quizPlatform");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WelcomeReadytolearnLabel = uilabel(app.UIFigure);
            app.WelcomeReadytolearnLabel.Text = {'Welcome! Ready to learn ... ?'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.AButton = uibutton(app.Panel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.Panel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.Panel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.Panel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.Panel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.FButton = uibutton(app.Panel, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.Panel, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.HButton = uibutton(app.Panel, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.IButton = uibutton(app.Panel, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = 'I';
            app.JButton = uibutton(app.Panel, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.Panel, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.Panel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.MButton = uibutton(app.Panel, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.NButton = uibutton(app.Panel, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.UButton = uibutton(app.Panel, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.TButton = uibutton(app.Panel, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.SButton = uibutton(app.Panel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.RButton = uibutton(app.Panel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.QButton = uibutton(app.Panel, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.QButton.Text = 'Q';
            app.PButton = uibutton(app.Panel, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.OButton = uibutton(app.Panel, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.ZButton = uibutton(app.Panel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.YButton = uibutton(app.Panel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.XButton = uibutton(app.Panel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.WButton = uibutton(app.Panel, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.VButton = uibutton(app.Panel, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.ClickonthebuttonsandlearnLabel = uilabel(app.UIFigure);
            app.ClickonthebuttonsandlearnLabel.Text = 'Click on the buttons and learn ...';
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = {'Main'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-ice-cream-24.png';
            app.Button.Text = '';
            app.ClicktheSpeakerLabel = uilabel(app.Panel_2);
            app.ClicktheSpeakerLabel.Text = '>>>  Click  the Speaker';
            app.TakeaQuizButton = uibutton(app.UIFigure, 'push');
            app.TakeaQuizButton.ButtonPushedFcn = createCallbackFcn(app, @TakeaQuizButtonPushed, true);
            app.TakeaQuizButton.Text = 'Take a Quiz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learningPlatform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end