classdef quizPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        ButtonGroup          matlab.ui.container.ButtonGroup
        AppleButton          matlab.ui.control.RadioButton
        BananaButton         matlab.ui.control.RadioButton
        CookieButton         matlab.ui.control.RadioButton
        DollButton           matlab.ui.control.RadioButton
        ElephantButton       matlab.ui.control.RadioButton
        CheckAnswerButton    matlab.ui.control.Button
        FanButton            matlab.ui.control.RadioButton
        mangoButton          matlab.ui.control.RadioButton
        nestButton           matlab.ui.control.RadioButton
        orangeButton         matlab.ui.control.RadioButton
        PakistanButton       matlab.ui.control.RadioButton
        QuraanButton         matlab.ui.control.RadioButton
        ratButton            matlab.ui.control.RadioButton
        GunButton            matlab.ui.control.RadioButton
        HorseButton          matlab.ui.control.RadioButton
        IglooButton          matlab.ui.control.RadioButton
        snakeButton          matlab.ui.control.RadioButton
        turtleButton         matlab.ui.control.RadioButton
        umbrellaButton       matlab.ui.control.RadioButton
        JellyButton          matlab.ui.control.RadioButton
        kangarooButton       matlab.ui.control.RadioButton
        lionButton           matlab.ui.control.RadioButton
        violinButton         matlab.ui.control.RadioButton
        windowButton         matlab.ui.control.RadioButton
        xylophoneButton      matlab.ui.control.RadioButton
        yoyoButton           matlab.ui.control.RadioButton
        zebraButton          matlab.ui.control.RadioButton
        Button22             matlab.ui.control.RadioButton
        Label                matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        btnLife1             matlab.ui.control.Button
        btnLife2             matlab.ui.control.Button
        btnLife3             matlab.ui.control.Button
        LifesRemainingLabel  matlab.ui.control.Label
        lblremLife           matlab.ui.control.Label
        PointsLabel          matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        pnlGameOver          matlab.ui.container.Panel
        GameOverLabel        matlab.ui.control.Label
        PointsSecuredLabel   matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        PlayAgainButton      matlab.ui.control.Button
        ExitButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        correctValue; % Description
        pointCounter =0;
        lifeLeft = 3;
    end
    
    methods (Access = private)
        
        function randomPicGenerator(app)
            d='H:\6th smester 2k19\DIP PRoject\Training of models';
            f=dir([d '\*.jpg']);
            n=numel(f);
            idx=randi(n);
            im=imread(f(idx).name);
            app.correctValue = f(idx).name ;
            imshow(im, 'parent', app.UIAxes);
        end
  
        
        function checkLifeLine(app)
            if app.lifeLeft == 3
                app.btnLife3.Visible = 'off';
            elseif app.lifeLeft == 2
                app.btnLife2.Visible = 'off';
                
            elseif app.lifeLeft == 1
                app.btnLife1.Visible = 'off';
                app.Panel.Visible='off';
                app.ButtonGroup.Visible='off';
                app.UIAxes.Visible  = 'off';
                app.pnlGameOver.Visible= 'on';
                app.Label_3.Text = string(app.pointCounter);
            
                
             end
        end
        
        function pointsCalculator(app)
            app.pointCounter = app.pointCounter + 10;
            app.Label_2.Text = string(app.pointCounter);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.randomPicGenerator();
            app.pnlGameOver.Visible = 'off';
        end

        % Callback function
        function ButtonPushed(app, event)
           
        end

        % Button pushed function: CheckAnswerButton
        function CheckAnswerButtonPushed(app, event)
         switch app.ButtonGroup.SelectedObject.Text
            case 'Apple' %where "Button1" is the name of the first radio button
                if strcmp(string(app.correctValue) , 'A.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                    app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
            case 'Banana'
                if strcmp(string(app.correctValue) , 'b.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                   
                else
                     app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                    
                end
            case 'Cookie'
                if strcmp(string(app.correctValue) , 'c.jpg')
                   app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'Doll'
                if strcmp(string(app.correctValue) , 'd.jpg')
                   app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'Elephant'
                if strcmp(string(app.correctValue) , 'E.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end   
             case 'Fan'
                if strcmp(string(app.correctValue) , 'F.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end    
             case 'Gun'
                if strcmp(string(app.correctValue) , 'G.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'Horse'
                if strcmp(string(app.correctValue) , 'H.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end  
              case 'Igloo'
                if strcmp(string(app.correctValue) , 'I.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end  
             case 'Jelly'
                if strcmp(string(app.correctValue) , 'J.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end  
             case 'kangaroo'
                if strcmp(string(app.correctValue) , 'K.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end   
            case 'lion'
                if strcmp(string(app.correctValue) , 'J.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
            case 'mango'
                if strcmp(string(app.correctValue) , 'M.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'nest'
                if strcmp(string(app.correctValue) , 'N.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'orange'
                if strcmp(string(app.correctValue) , 'O.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
         case 'Pakistan'
                if strcmp(string(app.correctValue) , 'P.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
         
        
        case 'Quraan'
                if strcmp(string(app.correctValue) , 'Q.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
        case 'rat'
                if strcmp(string(app.correctValue) , 'R.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end   
             case 'snake'
                if strcmp(string(app.correctValue) , 'S.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end   
             case 'turtle'
                if strcmp(string(app.correctValue) , 'T.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end    
             case 'umbrella'
                if strcmp(string(app.correctValue) , 'U.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
                case 'violin'
                if strcmp(string(app.correctValue) , 'V.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
              case 'window'
                if strcmp(string(app.correctValue) , 'W.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end  
                
             case 'xylophone'
                if strcmp(string(app.correctValue) , 'X.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
                
                case 'yo yo'
                if strcmp(string(app.correctValue) , 'Y.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                      app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
             case 'zebra'
                if strcmp(string(app.correctValue) , 'Z.jpg')
                    app.Label.Text = "correct!"; app.Label.FontColor = 'green'; app.randomPicGenerator();app.pointsCalculator();
                else
                       app.checkLifeLine(); app.Label.Text = "incorrect!"; app.Label.FontColor = 'red';app.lifeLeft = app.lifeLeft-1;app.lblremLife.Text = string(app.lifeLeft);
                end
           
         end
        end

        % Callback function
        function NextButtonPushed(app, event)
            app.randomPicGenerator();
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
            closereq;
            run("quizPlatform");
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
            run("application");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.AppleButton = uiradiobutton(app.ButtonGroup);
            app.AppleButton.Text = 'Apple';
            app.AppleButton.Value = true;
            app.BananaButton = uiradiobutton(app.ButtonGroup);
            app.BananaButton.Text = 'Banana';
            app.CookieButton = uiradiobutton(app.ButtonGroup);
            app.CookieButton.Text = 'Cookie';
            app.DollButton = uiradiobutton(app.ButtonGroup);
            app.DollButton.Text = 'Doll';
            app.ElephantButton = uiradiobutton(app.ButtonGroup);
            app.ElephantButton.Text = 'Elephant';
            app.CheckAnswerButton = uibutton(app.ButtonGroup, 'push');
            app.CheckAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswerButtonPushed, true);
            app.CheckAnswerButton.Text = 'Check Answer';
            app.FanButton = uiradiobutton(app.ButtonGroup);
            app.FanButton.Text = 'Fan';
            app.mangoButton = uiradiobutton(app.ButtonGroup);
            app.mangoButton.Text = 'mango';
            app.nestButton = uiradiobutton(app.ButtonGroup);
            app.nestButton.Text = 'nest';
            app.orangeButton = uiradiobutton(app.ButtonGroup);
            app.orangeButton.Text = 'orange';
            app.PakistanButton = uiradiobutton(app.ButtonGroup);
            app.PakistanButton.Text = 'Pakistan';
            app.QuraanButton = uiradiobutton(app.ButtonGroup);
            app.QuraanButton.Text = 'Quraan';
            app.ratButton = uiradiobutton(app.ButtonGroup);
            app.ratButton.Text = 'rat';
            app.GunButton = uiradiobutton(app.ButtonGroup);
            app.GunButton.Text = 'Gun';
            app.HorseButton = uiradiobutton(app.ButtonGroup);
            app.HorseButton.Text = 'Horse';
            app.IglooButton = uiradiobutton(app.ButtonGroup);
            app.IglooButton.Text = 'Igloo';
            app.snakeButton = uiradiobutton(app.ButtonGroup);
            app.snakeButton.Text = 'snake';
            app.turtleButton = uiradiobutton(app.ButtonGroup);
            app.turtleButton.Text = 'turtle';
            app.umbrellaButton = uiradiobutton(app.ButtonGroup);
            app.umbrellaButton.Text = 'umbrella';
            app.JellyButton = uiradiobutton(app.ButtonGroup);
            app.JellyButton.Text = 'Jelly';
            app.kangarooButton = uiradiobutton(app.ButtonGroup);
            app.kangarooButton.Text = 'kangaroo';
            app.lionButton = uiradiobutton(app.ButtonGroup);
            app.lionButton.Text = 'lion';
            app.violinButton = uiradiobutton(app.ButtonGroup);
            app.violinButton.Text = 'violin';
            app.windowButton = uiradiobutton(app.ButtonGroup);
            app.windowButton.Text = 'window';
            app.xylophoneButton = uiradiobutton(app.ButtonGroup);
            app.xylophoneButton.Text = 'xylophone';
            app.yoyoButton = uiradiobutton(app.ButtonGroup);
            app.yoyoButton.Text = 'yo yo';
            app.zebraButton = uiradiobutton(app.ButtonGroup);
            app.zebraButton.Text = 'zebra';
            app.Button22 = uiradiobutton(app.ButtonGroup);
            app.Button22.Text = 'Button22';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = ' ';
            app.Panel = uipanel(app.UIFigure);
            app.btnLife1 = uibutton(app.Panel, 'push');
            app.btnLife1.Icon = 'like.png';
            app.btnLife1.Text = ' ';
            app.btnLife2 = uibutton(app.Panel, 'push');
            app.btnLife2.Icon = 'like.png';
            app.btnLife2.Text = ' ';
            app.btnLife3 = uibutton(app.Panel, 'push');
            app.btnLife3.Icon = 'like.png';
            app.btnLife3.Text = ' ';
            app.LifesRemainingLabel = uilabel(app.Panel);
            app.LifesRemainingLabel.Text = 'Lifes Remaining :';
            app.lblremLife = uilabel(app.Panel);
            app.lblremLife.Text = '3';
            app.PointsLabel = uilabel(app.Panel);
            app.PointsLabel.Text = 'Points:';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ' ';
            app.pnlGameOver = uipanel(app.UIFigure);
            app.pnlGameOver.Title = ' ';
            app.GameOverLabel = uilabel(app.pnlGameOver);
            app.GameOverLabel.Text = {'Game Over'; ''};
            app.PointsSecuredLabel = uilabel(app.pnlGameOver);
            app.PointsSecuredLabel.Text = 'Points: Secured: ';
            app.Label_3 = uilabel(app.pnlGameOver);
            app.Label_3.Text = ' ';
            app.PlayAgainButton = uibutton(app.pnlGameOver, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again!';
            app.ExitButton = uibutton(app.pnlGameOver, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quizPlatform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end